classdef magcalculate_cn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        magnetTab             matlab.ui.container.Tab
        Switch                matlab.ui.control.Switch
        HUSTMSELabel_2        matlab.ui.control.Label
        CH_panpanLabel        matlab.ui.control.Label
        magnet_type           matlab.ui.control.DropDown
        Label_10              matlab.ui.control.Label
        Label_9               matlab.ui.control.Label
        HUSTMSELabel          matlab.ui.control.Label
        ZR_heiheiLabel        matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        ButtonGroup_shuchu_2  matlab.ui.container.ButtonGroup
        zButton_3             matlab.ui.control.RadioButton
        yButton_3             matlab.ui.control.RadioButton
        xButton_3             matlab.ui.control.RadioButton
        endpoint              matlab.ui.control.EditField
        Label_8               matlab.ui.control.Label
        beginpoint            matlab.ui.control.EditField
        Label_7               matlab.ui.control.Label
        Bcalcu                matlab.ui.control.Button
        addpoint              matlab.ui.control.Button
        delpoint              matlab.ui.control.Button
        ButtonGroup_shuchu    matlab.ui.container.ButtonGroup
        zButton_2             matlab.ui.control.RadioButton
        yButton_2             matlab.ui.control.RadioButton
        xButton_2             matlab.ui.control.RadioButton
        load_button           matlab.ui.control.Button
        mmLabel_2             matlab.ui.control.Label
        mmLabel               matlab.ui.control.Label
        delbutton             matlab.ui.control.Button
        Label_6               matlab.ui.control.Label
        ButtonGroup_2         matlab.ui.container.ButtonGroup
        mmLabel_3             matlab.ui.control.Label
        dotnum                matlab.ui.control.EditField
        moverange             matlab.ui.control.EditField
        zButton               matlab.ui.control.RadioButton
        yButton               matlab.ui.control.RadioButton
        xButton               matlab.ui.control.RadioButton
        save_button           matlab.ui.control.Button
        magnetdir             matlab.ui.control.EditField
        Label_5               matlab.ui.control.Label
        begin_magnet          matlab.ui.control.Button
        mNNmTLabel            matlab.ui.control.Label
        color_float           matlab.ui.control.EditField
        Label_4               matlab.ui.control.Label
        color_fixd            matlab.ui.control.EditField
        Label_3               matlab.ui.control.Label
        clearmagnet           matlab.ui.control.Button
        add_magnet            matlab.ui.control.Button
        ButtonGroup           matlab.ui.container.ButtonGroup
        mag                   matlab.ui.control.EditField
        maggrade              matlab.ui.control.EditField
        Button_2              matlab.ui.control.RadioButton
        Button                matlab.ui.control.RadioButton
        magnetposition        matlab.ui.control.EditField
        Label_2               matlab.ui.control.Label
        magnetsize            matlab.ui.control.EditField
        Label                 matlab.ui.control.Label
        fixorfloat            matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cuboid_cell={[] []}; % Description
        magnet_draw_hand=[]; % Description
        cylinder_cell={[] []}; % Description
        c
        K_xyz_zong; % Description
        F_xyz_zong; % Description
        dx; % Description
        Num_fixed=0;% Description
        Num_float=0;% Description
        begin_point=0;
        end_point=0; % Description
        point_hand=[]; % Description
        B_xyz_zong; % Description
       
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %该函数在 App 启动时、但在用户与 UI 进行交互之前执行。
            hold(app.UIAxes,'on');
            set(app.UIAxes,'DataAspectRatio',[1 1 1]);                                      %数据单元的相对长度 [1 1 1] 
        end

        % Button pushed function: add_magnet
        function add_magnetButtonPushed(app, event)
            set(app.UIAxes,'DataAspectRatio',[1 1 1]);
            
            mag_type=app.magnet_type.Value; 
            switch mag_type
                case '矩形体'
                    m_type=['cuboid'];
                case '圆柱&圆环'
                    m_type=['cylinder'];
            end
            
            magnet_group=app.fixorfloat.Value;                                              %下拉框函数值
            switch magnet_group
                case '定子'
                    magnet_No=1;
                    app.Num_fixed=app.Num_fixed+1;
                case '动子'
                    magnet_No=2;
                    app.Num_float=app.Num_float+1;
            end
            
            dim_str=strsplit(app.magnetsize.Value,';');                                     %磁体尺寸
            if numel(dim_str) == 2
                dim_double=[str2double(dim_str{1})/1000;str2double(dim_str{2})/1000];
            elseif numel(dim_str) == 3
                dim_double=[str2double(dim_str{1})/1000;str2double(dim_str{2})/1000;str2double(dim_str{3})/1000];
                if (dim_double(1)>=dim_double(2)) && isequal(m_type,['cylinder'])
                    opts.Interpreter = 'tex'; 
                    % Include the desired Default answer
                    opts.Default = 'Ok';
                    % Use the TeX interpreter to format the question
                    quest = ['\fontname{仿宋}\fontsize{15} 圆环外径必须大于内径！请重新输入！'];
                    questdlg(quest,'圆环尺寸错误',...
                        'Ok',opts);
                    return;
                end
            end
            dir_str=strsplit(app.magnetdir.Value,';');                                      %磁化方向
            dir_double=[str2double(dir_str{1});str2double(dir_str{2});str2double(dir_str{3})];
            pos_str=strsplit(app.magnetposition.Value,';');                                 %磁体位置
            pos_double=[str2double(pos_str{1})/1000;str2double(pos_str{2})/1000;str2double(pos_str{3})/1000];
            gradeormagn=app.ButtonGroup.SelectedObject.Text;                                %磁体等级or充磁强度
            
            switch gradeormagn
                case '磁体等级'
                    grade=app.maggrade.Value;
                    magnet_new=magnetdefine('type',m_type,'dim',dim_double,...
                        'grade',grade,'magdir',dir_double,'position',pos_double);                           
                    
                case '磁体充磁强度'
                    magn=str2double(app.mag.Value);
                    magnet_new=magnetdefine('type',m_type,'dim',dim_double,...
                        'magn',magn,'magdir',dir_double,'position',pos_double);                             
            end
            
            if magnet_No==1%是定子
                color_str=strsplit(app.color_fixd.Value,';');
                color_double=[str2double(color_str{1});str2double(color_str{2});str2double(color_str{3})];
                magnet_new.color=color_double;
                app.magnet_draw_hand=[app.magnet_draw_hand;magnetdraw(magnet_new,[0;0;0],'ax',app.UIAxes)]; 
            end
            if magnet_No==2%是动子
                color_str=strsplit(app.color_float.Value,';');
                color_double=[str2double(color_str{1});str2double(color_str{2});str2double(color_str{3})];
                magnet_new.color=color_double;
                app.magnet_draw_hand=[app.magnet_draw_hand;magnetdraw(magnet_new,[0;0;0],'ax',app.UIAxes)];
            end 
            switch m_type
                case 'cuboid'
                    app.cuboid_cell{size(app.cuboid_cell,1)+1,magnet_No}=magnet_new;
                    assignin('base','cuboid',app.cuboid_cell)
                case 'cylinder'
                    app.cylinder_cell{size(app.cylinder_cell,1)+1,magnet_No}=magnet_new;
                    assignin('base','cylinder',app.cylinder_cell)
            end
            
        end

        % Button pushed function: clearmagnet
        function clearmagnetButtonPushed(app, event)
            app.cuboid_cell={[] []};
            app.cylinder_cell={[] []};
            cla(app.UIAxes);
            app.Num_fixed=0;%定子
            app.Num_float=0;%动子
        end

        % Button pushed function: delbutton
        function delbuttonButtonPushed(app, event)
            mag_type=app.magnet_type.Value;
            switch mag_type
                case '矩形体'
                    if size(app.cuboid_cell,1)>1%判断现在的行数                                      %删除上一个
                        if length(app.cuboid_cell{size(app.cuboid_cell,1),1})==1                    %size(app.cuboid_cell,1) 得到cuboid_cell的第一维度的长度即磁体总数
                            app.Num_fixed=app.Num_fixed-1;
                        end
                        if length(app.cuboid_cell{size(app.cuboid_cell,1),2})==1
                            app.Num_float=app.Num_float-1;
                        end
                        app.cuboid_cell(size(app.cuboid_cell,1),:)=[];
                        assignin('base','cuboid',app.cuboid_cell)                                   %给工作区base中的cuboid幅值，即刷新
                        %去掉最后一个磁体的结构体
                        
                        delete(app.magnet_draw_hand(length(app.magnet_draw_hand)-1));               %删除绘图
                        delete(app.magnet_draw_hand(length(app.magnet_draw_hand)));                                 
                        app.magnet_draw_hand(length(app.magnet_draw_hand))=[];
                        app.magnet_draw_hand(length(app.magnet_draw_hand))=[];
                    else
                        opts.Interpreter = 'tex';
                        % Include the desired Default answer
                        opts.Default = 'Ok';
                        % Use the TeX interpreter to format the question
                        quest = ['\fontsize{13}','磁体已经全部清空'];  %%此处有改动
                        questdlg(quest,'warn','Ok',opts);
                    end
                case '圆柱&圆环'
                    if size(app.cylinder_cell,1)>1%判断现在的行数
                        if length(app.cylinder_cell{size(app.cylinder_cell,1),1})==1
                            app.Num_fixed=app.Num_fixed-1;
                            
                        end
                        if length(app.cylinder_cell{size(app.cylinder_cell,1),2})==1
                            app.Num_float=app.Num_float-1;
                           
                        end
                        
                        app.cylinder_cell(size(app.cylinder_cell,1),:)=[];
                        assignin('base','cuboid',app.cylinder_cell)
                        %去掉最后一个磁体的结构体
                        for iii=1:8
                            delete(app.magnet_draw_hand(length(app.magnet_draw_hand)));
                            app.magnet_draw_hand(length(app.magnet_draw_hand))=[];
                        end
                    else
                        opts.Interpreter = 'tex';
                        % Include the desired Default answer
                        opts.Default = 'Ok';
                        % Use the TeX interpreter to format the question
                        quest = ['\fontsize{13}','磁体已经全部清空'];  %%此处有改动
                        questdlg(quest,'warn','Ok',opts);
                    end
            end
        end

        % Button pushed function: begin_magnet
        function begin_magnetButtonPushed(app, event)
           
            mag_type=app.magnet_type.Value;

            opts.Interpreter = 'tex';
            % Include the desired Default answer
            opts.Default = 'Yes';
            % Use the TeX interpreter to format the question
            quest = ['\fontsize{13}','现有',int2str(app.Num_fixed),'个定磁体,',...
                int2str(app.Num_float),'个动磁体'];
            answer = questdlg(quest,'是否开始计算',...
                'Yes','No',opts);
            switch answer

                case 'Yes'
                    switch mag_type
                        case '矩形体'
                            %开始计算刚度和力
                            figure(1); clf; hold on; box on
                            N=str2double(app.dotnum.Value);                                         %%采样点数
                            dot_str=strsplit(app.moverange.Value,';');                              %%起始和终点位置
                            min=str2double(dot_str{1})/1000; max=str2double(dot_str{2})/1000;
                            displ=linspace(min,max,N);                                              %%生成线性等距向量
                            dir_move=app.ButtonGroup_2.SelectedObject.Text;                         %%动子运动方向
                            switch dir_move
                                case 'x'
                                    displ_range=[1;0;0]*displ;
                                case 'y'
                                    displ_range=[0;1;0]*displ;
                                case 'z'
                                    displ_range=[0;0;1]*displ;
                            end
                            app.F_xyz_zong=zeros(size(displ_range));                                %%创建全零数组
                            app.K_xyz_zong=zeros(size(displ_range));
                            for i=1:length(app.cuboid_cell)                                         %动子编号
                                if ~length(app.cuboid_cell{i,2}); continue;end
                                for j=1:length(app.cuboid_cell)                                     %定子编号
                                    if ~length(app.cuboid_cell{j,1}); continue;end
                                    F_xyz=magnetforces(app.cuboid_cell{j,1},app.cuboid_cell{i,2}...
                                        ,displ_range,'draw',true,'drawpath',true,'markpath',{'d','o','s'},'markpathN',2);
                                    K_xyz=magnetforces(app.cuboid_cell{j,1},app.cuboid_cell{i,2},displ_range,'stiffness');
                                    app.F_xyz_zong=app.F_xyz_zong+F_xyz;
                                    app.K_xyz_zong=app.K_xyz_zong+K_xyz;
                                end
                            end
                          
                            view(3)
                            
                            grid on
                            figure(2); clf; hold on; box on
                            dir_draw=app.ButtonGroup_shuchu.SelectedObject.Text;
                            switch dir_draw
                                case 'x'
                                    h1 = plot(displ,app.F_xyz_zong(1,:),'linewidth',2);
                                case 'y'
                                    h2 = plot(displ,app.F_xyz_zong(2,:),'linewidth',2);
                                case 'z'
                                    h3 = plot(displ,app.F_xyz_zong(3,:),'linewidth',2);
                            end


                            xlabel("Horizontal "+dir_move+" displacement, m",'interpreter','latex')
                            ylabel("Force $F_{"+num2str(dir_draw)+'}$'+', N','interpreter','latex')
                            yline(0,'--');
                            xline(0,'--');
                            
                            figure(3); clf; hold on; box on
                            dir_draw=app.ButtonGroup_shuchu.SelectedObject.Text;
                            switch dir_draw
                                case 'x'
                                    h1 = plot(displ,app.K_xyz_zong(1,:),'linewidth',2);
                                case 'y'
                                    h2 = plot(displ,app.K_xyz_zong(2,:),'linewidth',2);
                                case 'z'
                                    h3 = plot(displ,app.K_xyz_zong(3,:),'linewidth',2);
                            end

                            xlabel("Horizontal "+dir_move+" displacement, m",'interpreter','latex')
                            ylabel("Stiffness "+'$K_{'+num2str(dir_draw)+'}$'+", N/m",'interpreter','latex')
                            yline(0,'--');
                            xline(0,'--');

                            app.dx=displ;
                            %             ii = N/2;
                            %             text(displ(ii),app.F_xyz_zong(1,ii),'$F_x$','interpreter','LaTeX','backgroundcolor','white','color',h1.Color,'edgecolor',h1.Color,'horizontalalignment','center')
                            %             text(displ(ii),app.F_xyz_zong(2,ii),'$F_y$','interpreter','LaTeX','backgroundcolor','white','color',h2.Color,'edgecolor',h2.Color,'horizontalalignment','center')
                            %             text(displ(ii),app.F_xyz_zong(3,ii),'$F_z$','interpreter','LaTeX','backgroundcolor','white','color',h3.Color,'edgecolor',h3.Color,'horizontalalignment','center')
                        case '圆柱&圆环'
                            %开始计算刚度和力
                            figure(1); clf; hold on; box on
                            N=str2double(app.dotnum.Value);                                         %%采样点数
                            dot_str=strsplit(app.moverange.Value,';');                              %%起始和终点位置
                            min=str2double(dot_str{1})/1000; max=str2double(dot_str{2})/1000;
                            displ=linspace(min,max,N);                                              %%生成线性等距向量
                            dir_move=app.ButtonGroup_2.SelectedObject.Text;                         %%动子运动方向
                            switch dir_move
                                case 'x'
                                    displ_range=[1;0;0]*displ;
                                case 'y'
                                    displ_range=[0;1;0]*displ;
                                case 'z'
                                    displ_range=[0;0;1]*displ;
                            end
                            app.F_xyz_zong=zeros(size(displ_range));                                %%创建全零数组

                            for i=1:length(app.cylinder_cell)                                         %动子编号
                                if ~length(app.cylinder_cell{i,2}); continue;end
                                for j=1:length(app.cylinder_cell)                                     %定子编号
                                    if ~length(app.cylinder_cell{j,1}); continue;end
                                    if numel(app.cylinder_cell{j,1}.dim)==3 && numel(app.cylinder_cell{i,2}.dim)==2
                                        magnetin=app.cylinder_cell{j,1};
                                        magnetout=app.cylinder_cell{j,1};
                                        magnetin.isring=0;
                                        magnetout.isring=0;
                                        magnetin.dim=[magnetin.dim(1);magnetin.dim(3)];
                                        magnetout.dim=[magnetout.dim(2);magnetout.dim(3)];
                                        magnetin.dir=-magnetin.dir;
                                        F_xyzin=magnetforces(magnetin,app.cylinder_cell{i,2}...
                                            ,displ_range,'draw',true,'drawpath',true,'markpath',{'d','o','s'},'markpathN',2);
                                        F_xyzout=magnetforces(magnetout,app.cylinder_cell{i,2}...
                                            ,displ_range,'draw',true,'drawpath',true,'markpath',{'d','o','s'},'markpathN',2);
                                        F_xyz=F_xyzin+F_xyzout;
                                        
                                    elseif numel(app.cylinder_cell{j,1}.dim)==2 && numel(app.cylinder_cell{i,2}.dim)==3
                                        magnetin=app.cylinder_cell{i,2};
                                        magnetout=app.cylinder_cell{i,2};
                                        magnetin.isring=0;
                                        magnetout.isring=0;
                                        magnetin.dim=[magnetin.dim(1);magnetin.dim(3)];
                                        magnetout.dim=[magnetout.dim(2);magnetout.dim(3)];
                                        magnetin.dir=-magnetin.dir;
                                        F_xyzin=magnetforces(app.cylinder_cell{j,1},magnetin...
                                            ,displ_range,'draw',true,'drawpath',true,'markpath',{'d','o','s'},'markpathN',2);
                                        F_xyzout=magnetforces(app.cylinder_cell{j,1},magnetout...
                                            ,displ_range,'draw',true,'drawpath',true,'markpath',{'d','o','s'},'markpathN',2);
                                        F_xyz=F_xyzin+F_xyzout;
                                    else
                                        F_xyz=magnetforces(app.cylinder_cell{j,1},app.cylinder_cell{i,2}...
                                            ,displ_range,'draw',true,'drawpath',true,'markpath',{'d','o','s'},'markpathN',2);
                                    end
                                    app.F_xyz_zong=app.F_xyz_zong+F_xyz;

                                end
                            end
                            
                            view(3)
                            grid on
                            figure(2); clf; hold on; box on
                            dir_draw=app.ButtonGroup_shuchu.SelectedObject.Text;
                            switch dir_draw
                                case 'x'
                                    h1 = plot(displ,app.F_xyz_zong(1,:),'linewidth',2);
                                case 'y'
                                    h2 = plot(displ,app.F_xyz_zong(2,:),'linewidth',2);
                                case 'z'
                                    h3 = plot(displ,app.F_xyz_zong(3,:),'linewidth',2);
                            end


                            xlabel("Horizontal "+dir_move+" displacement, m",'interpreter','latex')
                            ylabel("Force $F_{"+num2str(dir_draw)+'}$'+', N','interpreter','latex')
                            yline(0,'--');
                            xline(0,'--');


                            app.dx=displ;

                    end
            end
        end

        % Button pushed function: save_button
        function save_buttonButtonPushed(app, event)
            [dir_file,dir_path]=uiputfile();
            if dir_file~=0
                cuboid_cell=app.cuboid_cell;
                cylinder_cell=app.cylinder_cell;
                K_xyz_zong=app.K_xyz_zong;
                F_xyz_zong=app.F_xyz_zong;
                dx=app.dx;
                save([dir_path,dir_file],'cuboid_cell');
                save([dir_path,dir_file],'cylinder_cell','-append');
                save([dir_path,dir_file],'K_xyz_zong','-append');
                save([dir_path,dir_file],'F_xyz_zong','-append');
                save([dir_path,dir_file],'dx','-append'); 
            end
            
        end

        % Button pushed function: load_button
        function load_buttonButtonPushed(app, event)
            [dir_file,dir_path]=uigetfile();
            if dir_file~=0
                %                 delete(app.magnet_draw_hand(:));%删除绘图
                app.magnet_draw_hand=[];
                cylinder_cell={[] []}; 
                cuboid_cell={[] []}; 
                load([dir_path,dir_file]);
                
                if ~isequal(cuboid_cell,{[] []})
                    app.cuboid_cell=cuboid_cell;
                end
                if ~isequal(cylinder_cell,{[] []})
                    app.cylinder_cell=cylinder_cell;
                end

                app.Num_fixed=0;
                app.Num_float=0;
                if ~isequal(cuboid_cell,{[] []}) && isequal(cylinder_cell,{[] []});
                    app.magnet_type.Value='矩形体';
                    
                    for i=1:length(app.cuboid_cell) %动子编号
                        for j=1:2
                            if ~length(app.cuboid_cell{i,j}); continue;end
                            app.magnet_draw_hand=[app.magnet_draw_hand;magnetdraw(app.cuboid_cell{i,j},[0;0;0],'ax',app.UIAxes)];
                            
                            if j==1
                                app.Num_fixed=app.Num_fixed+1;
                            else
                                app.Num_float=app.Num_float+1;
                            end
                        end
                    end
                    assignin('base','cuboid',app.cuboid_cell)

                elseif isequal(cuboid_cell,{[] []}) && ~isequal(cylinder_cell,{[] []});
                    app.magnet_type.Value='圆柱&圆环';
                    
                    for i=1:length(app.cylinder_cell) %动子编号
                        for j=1:2
                            if ~length(app.cylinder_cell{i,j}); continue;end
                            app.magnet_draw_hand=[app.magnet_draw_hand;magnetdraw(app.cylinder_cell{i,j},[0;0;0],'ax',app.UIAxes)];
                            if j==1
                                app.Num_fixed=app.Num_fixed+1;
                            else
                                app.Num_float=app.Num_float+1;
                            end
                        end
                    end
                    assignin('base','cylinder',app.cylinder_cell)
                end
            end

        end

        % Button pushed function: addpoint
        function addpointButtonPushed(app, event)
            
            begin_str=strsplit(app.beginpoint.Value,';');%起始点
            begin_double=[str2double(begin_str{1})/1000;str2double(begin_str{2})/1000;...
                str2double(begin_str{3})/1000];
            
            end_str=strsplit(app.endpoint.Value,';');%终止点
            end_double=[str2double(end_str{1})/1000;str2double(end_str{2})/1000;...
                str2double(end_str{3})/1000];
            
            app.begin_point=begin_double;
            app.end_point=end_double;
            
            app.point_hand=[app.point_hand,plot3(app.UIAxes,[begin_double(1),end_double(1)], ...
                [begin_double(2),end_double(2)],[begin_double(3),end_double(3)],'-*',"LineWidth",2,'Color',[0,0,1])];%记录句柄


        end

        % Button pushed function: delpoint
        function delpointButtonPushed(app, event)
            
            app.end_point=0;
            app.begin_point=0;
            delete(app.point_hand(end));
            
        end

        % Button pushed function: Bcalcu
        function BcalcuButtonPushed(app, event)
            %计算磁感应强度
            disp_line_x=linspace(app.begin_point(1),app.end_point(1),500);
            disp_line_y=linspace(app.begin_point(2),app.end_point(2),500); 
            disp_line_z=linspace(app.begin_point(3),app.end_point(3),500); 
            displ=[0:1:499]*sqrt((disp_line_x(2)-disp_line_x(1))^2+(disp_line_y(2)-disp_line_y(1))^2 ...
                +(disp_line_z(2)-disp_line_z(1))^2)
            app.B_xyz_zong=zeros([3,500]);
            for j=1:length(app.cuboid_cell) %定子编号 
                if ~length(app.cuboid_cell{j,1}); continue;end
                B_xyz=magnetfield(app.cuboid_cell{j,1},[disp_line_x;disp_line_y;disp_line_z]);
                app.B_xyz_zong=app.B_xyz_zong+B_xyz;
            end
    
            figure(4); clf; hold on; box on
            dir_draw=app.ButtonGroup_shuchu_2.SelectedObject.Text;
            switch dir_draw
                case 'x'
                    h1 = plot(displ,app.B_xyz_zong(1,:),'linewidth',2);
                case 'y'
                    h2 = plot(displ,app.B_xyz_zong(2,:),'linewidth',2);
                case 'z'
                    h3 = plot(displ,app.B_xyz_zong(3,:),'linewidth',2);
            end
            
            xlabel("Horizontal "+dir_draw+' displacement, m','interpreter','latex')
            ylabel('Magnetic induction $B$, T','interpreter','latex')
            yline(0,'--');
            xline(0,'--');
            
        end

        % Value changed function: magnet_type
        function magnet_typeValueChanged(app, event)
            value = app.magnet_type.Value;

            if isequal(app.cuboid_cell,{[] []}) && isequal(app.cylinder_cell,{[] []})
            else

                opts.Interpreter = 'tex';
                % Include the desired Default answer
                opts.Default = '不更改';
                % Use the TeX interpreter to format the question
                quest = ['\fontname{仿宋}\fontsize{15} 更改磁体类型会删除现有数据'];
                answer = questdlg(quest,'更改磁体类型',...
                    '更改并保存','更改并清除数据','不更改',opts);
                switch answer
                    case '更改并保存'
                        [dir_file,dir_path]=uiputfile();
                        if dir_file~=0
                            cuboid_cell=app.cuboid_cell;
                            cylinder_cell=app.cylinder_cell;
                            K_xyz_zong=app.K_xyz_zong;
                            F_xyz_zong=app.F_xyz_zong;
                            dx=app.dx;
                            save([dir_path,dir_file],'cuboid_cell');
                            save([dir_path,dir_file],'cylinder_cell','-append');
                            save([dir_path,dir_file],'K_xyz_zong','-append');
                            save([dir_path,dir_file],'F_xyz_zong','-append');
                            save([dir_path,dir_file],'dx','-append');
                        end
                        app.cuboid_cell={[] []};
                        app.cylinder_cell={[] []};
                        cla(app.UIAxes);
                        app.Num_fixed=0;%定子
                        app.Num_float=0;%动子
                    case '更改并清除数据'
                        app.cuboid_cell={[] []};
                        app.cylinder_cell={[] []};
                        cla(app.UIAxes);
                        app.Num_fixed=0;%定子
                        app.Num_float=0;%动子
                    case '不更改'
                        switch value
                            case '矩形体'
                                app.magnet_type.Value='圆柱&圆环';
                            case '圆柱&圆环'
                                app.magnet_type.Value='矩形体';
                        end
                end
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if isequal(value,'关')
                app.UIAxes.View = [-35 35];
                app.UIAxes.XLim = [-0.1 0.1];
                app.UIAxes.YLim = [-0.1 0.1];
                app.UIAxes.ZLim = [-0.1 0.1];
                app.UIAxes.ALim = [0 1];
            elseif isequal(value,'开')
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                app.UIAxes.ZLimMode = 'auto';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.magnetTab = uitab(app.TabGroup);
            app.magnetTab.Title = 'magnet';
            app.UIAxes = uiaxes(app.magnetTab);
            title(app.UIAxes, '立方磁体相互作用力计算')
            xlabel(app.UIAxes, 'X(m)')
            ylabel(app.UIAxes, 'Y(m)')
            zlabel(app.UIAxes, 'Z(m)')
            app.UIAxes.XLim = [-0.1 0.1];
            app.UIAxes.YLim = [-0.1 0.1];
            app.UIAxes.ZLim = [-0.1 0.1];
            app.UIAxes.ALim = [0 1];
            app.DropDownLabel = uilabel(app.magnetTab);
            app.DropDownLabel.Text = '磁体组别';
            app.fixorfloat = uidropdown(app.magnetTab);
            app.fixorfloat.Items = {'定子', '动子'};
            app.fixorfloat.Value = '定子';
            app.Label = uilabel(app.magnetTab);
            app.Label.Text = '磁体尺寸';
            app.magnetsize = uieditfield(app.magnetTab, 'text');
            app.magnetsize.Tooltip = {'添加圆柱时输入'; '(半径；高度)'; '添加圆环时输入'; '(内径；外径；高度)'};
            app.magnetsize.Value = '10;10;10';
            app.Label_2 = uilabel(app.magnetTab);
            app.Label_2.Text = '磁体位置';
            app.magnetposition = uieditfield(app.magnetTab, 'text');
            app.magnetposition.Value = '10;10;10';
            app.ButtonGroup = uibuttongroup(app.magnetTab);
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '磁体等级';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '磁体充磁强度';
            app.maggrade = uieditfield(app.ButtonGroup, 'text');
            app.maggrade.Value = 'N40';
            app.mag = uieditfield(app.ButtonGroup, 'text');
            app.mag.Value = '1.38';
            app.add_magnet = uibutton(app.magnetTab, 'push');
            app.add_magnet.ButtonPushedFcn = createCallbackFcn(app, @add_magnetButtonPushed, true);
            app.add_magnet.Text = '添加';
            app.clearmagnet = uibutton(app.magnetTab, 'push');
            app.clearmagnet.ButtonPushedFcn = createCallbackFcn(app, @clearmagnetButtonPushed, true);
            app.clearmagnet.Text = '清除';
            app.Label_3 = uilabel(app.magnetTab);
            app.Label_3.Text = '定子磁体颜色';
            app.color_fixd = uieditfield(app.magnetTab, 'text');
            app.color_fixd.Value = '1;0;0';
            app.Label_4 = uilabel(app.magnetTab);
            app.Label_4.Text = '动子磁体颜色';
            app.color_float = uieditfield(app.magnetTab, 'text');
            app.color_float.Value = '0;1;0';
            app.mNNmTLabel = uilabel(app.magnetTab);
            app.mNNmTLabel.Text = '单位：m，N，N/m,T';
            app.begin_magnet = uibutton(app.magnetTab, 'push');
            app.begin_magnet.ButtonPushedFcn = createCallbackFcn(app, @begin_magnetButtonPushed, true);
            app.begin_magnet.Text = '计算';
            app.Label_5 = uilabel(app.magnetTab);
            app.Label_5.Text = '磁化方向';
            app.magnetdir = uieditfield(app.magnetTab, 'text');
            app.magnetdir.Value = '0;0;1';
            app.save_button = uibutton(app.magnetTab, 'push');
            app.save_button.ButtonPushedFcn = createCallbackFcn(app, @save_buttonButtonPushed, true);
            app.save_button.Text = '保存数据';
            app.ButtonGroup_2 = uibuttongroup(app.magnetTab);
            app.xButton = uiradiobutton(app.ButtonGroup_2);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.ButtonGroup_2);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.ButtonGroup_2);
            app.zButton.Text = 'z';
            app.moverange = uieditfield(app.ButtonGroup_2, 'text');
            app.moverange.Value = '-50;50';
            app.dotnum = uieditfield(app.ButtonGroup_2, 'text');
            app.dotnum.Value = '500';
            app.mmLabel_3 = uilabel(app.ButtonGroup_2);
            app.mmLabel_3.Text = 'mm';
            app.Label_6 = uilabel(app.magnetTab);
            app.Label_6.Text = '动子运动：';
            app.delbutton = uibutton(app.magnetTab, 'push');
            app.delbutton.ButtonPushedFcn = createCallbackFcn(app, @delbuttonButtonPushed, true);
            app.delbutton.Text = '删除';
            app.mmLabel = uilabel(app.magnetTab);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.magnetTab);
            app.mmLabel_2.Text = 'mm';
            app.load_button = uibutton(app.magnetTab, 'push');
            app.load_button.ButtonPushedFcn = createCallbackFcn(app, @load_buttonButtonPushed, true);
            app.load_button.Text = '读取数据';
            app.ButtonGroup_shuchu = uibuttongroup(app.magnetTab);
            app.xButton_2 = uiradiobutton(app.ButtonGroup_shuchu);
            app.xButton_2.Text = 'x';
            app.xButton_2.Value = true;
            app.yButton_2 = uiradiobutton(app.ButtonGroup_shuchu);
            app.yButton_2.Text = 'y';
            app.zButton_2 = uiradiobutton(app.ButtonGroup_shuchu);
            app.zButton_2.Text = 'z';
            app.Panel = uipanel(app.magnetTab);
            app.Panel.Title = '计算磁感应强度';
            app.delpoint = uibutton(app.Panel, 'push');
            app.delpoint.ButtonPushedFcn = createCallbackFcn(app, @delpointButtonPushed, true);
            app.delpoint.Text = '删除';
            app.addpoint = uibutton(app.Panel, 'push');
            app.addpoint.ButtonPushedFcn = createCallbackFcn(app, @addpointButtonPushed, true);
            app.addpoint.Text = '添加';
            app.Bcalcu = uibutton(app.Panel, 'push');
            app.Bcalcu.ButtonPushedFcn = createCallbackFcn(app, @BcalcuButtonPushed, true);
            app.Bcalcu.Text = '计算';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '起始点';
            app.beginpoint = uieditfield(app.Panel, 'text');
            app.beginpoint.Value = '20;0;0';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '终止点';
            app.endpoint = uieditfield(app.Panel, 'text');
            app.endpoint.Value = '20;10;0';
            app.ButtonGroup_shuchu_2 = uibuttongroup(app.Panel);
            app.xButton_3 = uiradiobutton(app.ButtonGroup_shuchu_2);
            app.xButton_3.Text = 'x';
            app.xButton_3.Value = true;
            app.yButton_3 = uiradiobutton(app.ButtonGroup_shuchu_2);
            app.yButton_3.Text = 'y';
            app.zButton_3 = uiradiobutton(app.ButtonGroup_shuchu_2);
            app.zButton_3.Text = 'z';
            app.ZR_heiheiLabel = uilabel(app.magnetTab);
            app.ZR_heiheiLabel.Text = 'ZR_heihei';
            app.HUSTMSELabel = uilabel(app.magnetTab);
            app.HUSTMSELabel.Text = 'HUST MSE';
            app.Label_9 = uilabel(app.magnetTab);
            app.Label_9.Text = '只计算定子磁体磁感应强度';
            app.Label_10 = uilabel(app.magnetTab);
            app.Label_10.Text = '磁体类型';
            app.magnet_type = uidropdown(app.magnetTab);
            app.magnet_type.Items = {'矩形体', '圆柱&圆环'};
            app.magnet_type.ValueChangedFcn = createCallbackFcn(app, @magnet_typeValueChanged, true);
            app.magnet_type.Value = '矩形体';
            app.CH_panpanLabel = uilabel(app.magnetTab);
            app.CH_panpanLabel.Text = 'CH_panpan';
            app.HUSTMSELabel_2 = uilabel(app.magnetTab);
            app.HUSTMSELabel_2.Text = 'HUST MSE';
            app.Switch = uiswitch(app.magnetTab, 'slider');
            app.Switch.Items = {'关', '开'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'坐标轴自适应大小'};
            app.Switch.Value = '关';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = magcalculate_cn

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end