classdef magcalculate_en < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        cubiodTab                   matlab.ui.container.Tab
        groupLabel                  matlab.ui.control.Label
        fixorfloat                  matlab.ui.control.DropDown
        sizeLabel                   matlab.ui.control.Label
        cuboidsize                  matlab.ui.control.EditField
        locationLabel               matlab.ui.control.Label
        cuboidposition              matlab.ui.control.EditField
        ButtonGroup                 matlab.ui.container.ButtonGroup
        maggradeButton              matlab.ui.control.RadioButton
        magnButton                  matlab.ui.control.RadioButton
        maggrade                    matlab.ui.control.EditField
        mag                         matlab.ui.control.EditField
        add_cubiod                  matlab.ui.control.Button
        clearcubiod                 matlab.ui.control.Button
        StatorcolorLabel            matlab.ui.control.Label
        color_fixd                  matlab.ui.control.EditField
        MovercolorLabel             matlab.ui.control.Label
        color_float                 matlab.ui.control.EditField
        unitsmNNmTLabel             matlab.ui.control.Label
        begin_cuboid                matlab.ui.control.Button
        mag_dirLabel                matlab.ui.control.Label
        cuboiddir                   matlab.ui.control.EditField
        save_button                 matlab.ui.control.Button
        ButtonGroup_2               matlab.ui.container.ButtonGroup
        xButton                     matlab.ui.control.RadioButton
        yButton                     matlab.ui.control.RadioButton
        zButton                     matlab.ui.control.RadioButton
        moverange                   matlab.ui.control.EditField
        dotnum                      matlab.ui.control.EditField
        mmLabel_3                   matlab.ui.control.Label
        movementLabel               matlab.ui.control.Label
        delbutton                   matlab.ui.control.Button
        mmLabel                     matlab.ui.control.Label
        mmLabel_2                   matlab.ui.control.Label
        load_button                 matlab.ui.control.Button
        ButtonGroup_shuchu          matlab.ui.container.ButtonGroup
        xButton_2                   matlab.ui.control.RadioButton
        yButton_2                   matlab.ui.control.RadioButton
        zButton_2                   matlab.ui.control.RadioButton
        calculationofBonlinePanel   matlab.ui.container.Panel
        delpoint                    matlab.ui.control.Button
        addpoint                    matlab.ui.control.Button
        Bcalcu                      matlab.ui.control.Button
        point1Label                 matlab.ui.control.Label
        beginpoint                  matlab.ui.control.EditField
        point2Label                 matlab.ui.control.Label
        endpoint                    matlab.ui.control.EditField
        ButtonGroup_shuchu_2        matlab.ui.container.ButtonGroup
        xButton_3                   matlab.ui.control.RadioButton
        yButton_3                   matlab.ui.control.RadioButton
        zButton_3                   matlab.ui.control.RadioButton
        ZR_heiheiLabel              matlab.ui.control.Label
        HUSTMSELabel                matlab.ui.control.Label
        onlytheBgeneratedbyLabel    matlab.ui.control.Label
        thestatoriscalculatedLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cuboid_cell={[] []}; % Description
        cuboid_draw_hand=[]; % Description
        K_xyz_zong; % Description
        F_xyz_zong; % Description
        dx; % Description
        Num_fixed=0;% Description
        Num_float=0;% Description
        begin_point=0;
        end_point=0; % Description
        point_hand=[]; % Description
        B_xyz_zong; % Description
       
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            hold(app.UIAxes,'on');
            set(app.UIAxes,'DataAspectRatio',[1 1 1]);
        end

        % Button pushed function: add_cubiod
        function add_cubiodButtonPushed(app, event)
            set(app.UIAxes,'DataAspectRatio',[1 1 1]);
            
            cuboid_group=app.fixorfloat.Value;
            switch cuboid_group
                case '定子'
                    cuboid_No=1;
                    app.Num_fixed=app.Num_fixed+1;
                case '动子'
                    cuboid_No=2;
                    app.Num_float=app.Num_float+1;
            end
            
            dim_str=strsplit(app.cuboidsize.Value,';');%磁体尺寸
            dim_double=[str2double(dim_str{1})/1000;str2double(dim_str{2})/1000;str2double(dim_str{3})/1000];
            dir_str=strsplit(app.cuboiddir.Value,';');%磁化方向
            dir_double=[str2double(dir_str{1});str2double(dir_str{2});str2double(dir_str{3})];
            pos_str=strsplit(app.cuboidposition.Value,';');
            pos_double=[str2double(pos_str{1})/1000;str2double(pos_str{2})/1000;str2double(pos_str{3})/1000];
            gradeormagn=app.ButtonGroup.SelectedObject.Text;
            
            switch gradeormagn
                case '磁体等级'
                    grade=app.maggrade.Value;
                    cuboid_new=magnetdefine('type','cuboid','dim',dim_double,...
                        'grade',grade,'magdir',dir_double,'position',pos_double);
                    
                case '磁体充磁强度'
                    magn=str2double(app.mag.Value);
                    cuboid_new=magnetdefine('type','cuboid','dim',dim_double,...
                        'magn',magn,'magdir',dir_double,'position',pos_double); 
            end
            
            if cuboid_No==1%是定子
                color_str=strsplit(app.color_fixd.Value,';');
                color_double=[str2double(color_str{1});str2double(color_str{2});str2double(color_str{3})];
                cuboid_new.color=color_double;
                app.cuboid_draw_hand=[app.cuboid_draw_hand;magnetdraw(cuboid_new,[0;0;0],'ax',app.UIAxes)];
            end
            if cuboid_No==2%是动子
                color_str=strsplit(app.color_float.Value,';');
                color_double=[str2double(color_str{1});str2double(color_str{2});str2double(color_str{3})];
                cuboid_new.color=color_double;
                app.cuboid_draw_hand=[app.cuboid_draw_hand;magnetdraw(cuboid_new,[0;0;0],'ax',app.UIAxes)];
            end
            
            app.cuboid_cell{size(app.cuboid_cell,1)+1,cuboid_No}=cuboid_new;
            assignin('base','cuboid',app.cuboid_cell)
        end

        % Button pushed function: clearcubiod
        function clearcubiodButtonPushed(app, event)
            app.cuboid_cell={[] []};
            cla(app.UIAxes);
            app.Num_fixed=0;
            app.Num_float=0;
        end

        % Button pushed function: delbutton
        function delbuttonButtonPushed(app, event)
            if size(app.cuboid_cell,1)>1%判断现在的行数 
                
                if length(app.cuboid_cell{size(app.cuboid_cell,1),1})==1
                    app.Num_fixed=app.Num_fixed-1;
                end
                if length(app.cuboid_cell{size(app.cuboid_cell,1),2})==1
                    app.Num_float=app.Num_float-1;
                end
                app.cuboid_cell(size(app.cuboid_cell,1),:)=[];
                assignin('base','cuboid',app.cuboid_cell)
               %去掉最后一个磁体的结构体
                delete(app.cuboid_draw_hand(length(app.cuboid_draw_hand)-1));%删除绘图
                delete(app.cuboid_draw_hand(length(app.cuboid_draw_hand)));
                app.cuboid_draw_hand(length(app.cuboid_draw_hand))=[];
                app.cuboid_draw_hand(length(app.cuboid_draw_hand))=[];
                
                
            else 
                opts.Interpreter = 'tex';
               % Include the desired Default answer
                opts.Default = 'Ok';
                % Use the TeX interpreter to format the question
                quest = ['\fontsize{13}','磁体已经全部清空'];  %%此处有改动
                answer = questdlg(quest,'warn','Ok',opts);
            end
            
        end

        % Button pushed function: begin_cuboid
        function begin_cuboidButtonPushed(app, event)
           
            
            opts.Interpreter = 'tex';
            % Include the desired Default answer
            opts.Default = 'Yes';
            % Use the TeX interpreter to format the question
            quest = ['\fontsize{13}','现有',int2str(app.Num_fixed),'个定磁体,',...
                int2str(app.Num_float),'个动磁体'];  %%此处有改动
            answer = questdlg(quest,'是否开始计算',...
                              'Yes','No',opts);
            switch answer
                
                case 'Yes'
                    %开始计算刚度和力
                    figure(1); clf; hold on; box on
                    N=str2double(app.dotnum.Value);
                    dot_str=strsplit(app.moverange.Value,';');
                    min=str2double(dot_str{1})/1000; max=str2double(dot_str{2})/1000;
                    displ=linspace(min,max,N);
                    dir_move=app.ButtonGroup_2.SelectedObject.Text;
                    switch dir_move
                        case 'x'
                            displ_range=[1;0;0]*displ;
                        case 'y'
                            displ_range=[0;1;0]*displ;
                        case 'z'
                            displ_range=[0;0;1]*displ;
                    end
                    app.F_xyz_zong=zeros(size(displ_range));
                    app.K_xyz_zong=zeros(size(displ_range));
                    
                    for i=1:length(app.cuboid_cell) %动子编号
                        if ~length(app.cuboid_cell{i,2}); continue;end  
                        for j=1:length(app.cuboid_cell) %定子编号
                            if ~length(app.cuboid_cell{j,1}); continue;end
                            F_xyz=magnetforces(app.cuboid_cell{j,1},app.cuboid_cell{i,2}...
                                ,displ_range,'draw',true,'drawpath',true,'markpath',{'d','o','s'},'markpathN',2);
                            K_xyz=magnetforces(app.cuboid_cell{j,1},app.cuboid_cell{i,2},displ_range,'stiffness');
                            app.F_xyz_zong=app.F_xyz_zong+F_xyz;
                            app.K_xyz_zong=app.K_xyz_zong+K_xyz;
                        end
                    end
                    axis equal
                    view(3)
                    grid on
                    figure(2); clf; hold on; box on
                    dir_draw=app.ButtonGroup_shuchu.SelectedObject.Text;
                    switch dir_draw
                        case 'x'
                            h1 = plot(displ,app.F_xyz_zong(1,:),'linewidth',2);
                        case 'y'
                            h2 = plot(displ,app.F_xyz_zong(2,:),'linewidth',2);
                        case 'z'
                            h3 = plot(displ,app.F_xyz_zong(3,:),'linewidth',2);
                    end
                    
                    
                    xlabel('Horizontal $x$ displacement, m','interpreter','latex')
                    ylabel('Force $F_i$, N','interpreter','latex')
                    yline(0,'--');
                    xline(0,'--');
                    
                    figure(3); clf; hold on; box on
                    dir_draw=app.ButtonGroup_shuchu.SelectedObject.Text;
                    switch dir_draw
                        case 'x'
                            h1 = plot(displ,app.K_xyz_zong(1,:),'linewidth',2);
                        case 'y'
                            h2 = plot(displ,app.K_xyz_zong(2,:),'linewidth',2);
                        case 'z'
                            h3 = plot(displ,app.K_xyz_zong(3,:),'linewidth',2);
                    end
                    
                    xlabel('Horizontal $x$ displacement, m','interpreter','latex')
                    ylabel('Stiffness $K_i$, N/m','interpreter','latex')
                    yline(0,'--');
                    xline(0,'--');
                    
                    app.dx=displ;
        %             ii = N/2;
        %             text(displ(ii),app.F_xyz_zong(1,ii),'$F_x$','interpreter','LaTeX','backgroundcolor','white','color',h1.Color,'edgecolor',h1.Color,'horizontalalignment','center')
        %             text(displ(ii),app.F_xyz_zong(2,ii),'$F_y$','interpreter','LaTeX','backgroundcolor','white','color',h2.Color,'edgecolor',h2.Color,'horizontalalignment','center')
        %             text(displ(ii),app.F_xyz_zong(3,ii),'$F_z$','interpreter','LaTeX','backgroundcolor','white','color',h3.Color,'edgecolor',h3.Color,'horizontalalignment','center')

            end
        end

        % Button pushed function: save_button
        function save_buttonButtonPushed(app, event)
            [dir_file,dir_path]=uiputfile();
            if dir_file~=0
                cuboid_cell=app.cuboid_cell;
                K_xyz_zong=app.K_xyz_zong;
                F_xyz_zong=app.F_xyz_zong;
                dx=app.dx;
                save([dir_path,dir_file],'cuboid_cell');
                save([dir_path,dir_file],'K_xyz_zong','-append');
                save([dir_path,dir_file],'F_xyz_zong','-append');
                save([dir_path,dir_file],'dx','-append'); 
            end
            
        end

        % Button pushed function: load_button
        function load_buttonButtonPushed(app, event)
            [dir_file,dir_path]=uigetfile();
            if dir_file~=0
                delete(app.cuboid_draw_hand(:));%删除绘图
                app.cuboid_draw_hand=[];
                load([dir_path,dir_file]);
                app.cuboid_cell=cuboid_cell;
                app.Num_fixed=0;
                app.Num_float=0;
                for i=1:length(app.cuboid_cell) %动子编号
                    for j=1:2
                        if ~length(app.cuboid_cell{i,j}); continue;end  
                        app.cuboid_draw_hand=[app.cuboid_draw_hand;magnetdraw(app.cuboid_cell{i,j},[0;0;0],'ax',app.UIAxes)];
                        if j==1
                            app.Num_fixed=app.Num_fixed+1;
                        else 
                            app.Num_float=app.Num_float+1;
                        end
                    end
                end
                
                assignin('base','cuboid',app.cuboid_cell)
            end
            
        end

        % Button pushed function: addpoint
        function addpointButtonPushed(app, event)
            
            begin_str=strsplit(app.beginpoint.Value,';');%起始点
            begin_double=[str2double(begin_str{1})/1000;str2double(begin_str{2})/1000;...
                str2double(begin_str{3})/1000];
            
            end_str=strsplit(app.endpoint.Value,';');%终止点
            end_double=[str2double(end_str{1})/1000;str2double(end_str{2})/1000;...
                str2double(end_str{3})/1000];
            
            app.begin_point=begin_double;
            app.end_point=end_double;
            
            app.point_hand=[app.point_hand,plot3(app.UIAxes,[begin_double(1),end_double(1)], ...
                [begin_double(2),end_double(2)],[begin_double(3),end_double(3)],'-*',"LineWidth",2,'Color',[0,0,1])];%记录句柄


        end

        % Button pushed function: delpoint
        function delpointButtonPushed(app, event)
            
            app.end_point=0;
            app.begin_point=0;
            delete(app.point_hand(end));
            
        end

        % Button pushed function: Bcalcu
        function BcalcuButtonPushed(app, event)
            %计算磁感应强度
            disp_line_x=linspace(app.begin_point(1),app.end_point(1),500);
            disp_line_y=linspace(app.begin_point(2),app.end_point(2),500); 
            disp_line_z=linspace(app.begin_point(3),app.end_point(3),500); 
            displ=[0:1:499]*sqrt((disp_line_x(2)-disp_line_x(1))^2+(disp_line_y(2)-disp_line_y(1))^2 ...
                +(disp_line_z(2)-disp_line_z(1))^2)
            app.B_xyz_zong=zeros([3,500]);
            for j=1:length(app.cuboid_cell) %定子编号 
                if ~length(app.cuboid_cell{j,1}); continue;end
                B_xyz=magnetfield(app.cuboid_cell{j,1},[disp_line_x;disp_line_y;disp_line_z]);
                app.B_xyz_zong=app.B_xyz_zong+B_xyz;
            end
    
            figure(4); clf; hold on; box on
            dir_draw=app.ButtonGroup_shuchu_2.SelectedObject.Text;
            switch dir_draw
                case 'x'
                    h1 = plot(displ,app.B_xyz_zong(1,:),'linewidth',2);
                case 'y'
                    h2 = plot(displ,app.B_xyz_zong(2,:),'linewidth',2);
                case 'z'
                    h3 = plot(displ,app.B_xyz_zong(3,:),'linewidth',2);
            end
            
            xlabel('Horizontal $x$ displacement, m','interpreter','latex')
            ylabel('Magnetic induction $B$, T','interpreter','latex')
            yline(0,'--');
            xline(0,'--');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.cubiodTab = uitab(app.TabGroup);
            app.cubiodTab.Title = 'cubiod';
            app.groupLabel = uilabel(app.cubiodTab);
            app.groupLabel.Text = 'group';
            app.fixorfloat = uidropdown(app.cubiodTab);
            app.fixorfloat.Items = {'stator', 'mover'};
            app.fixorfloat.Value = 'stator';
            app.sizeLabel = uilabel(app.cubiodTab);
            app.sizeLabel.Text = 'size';
            app.cuboidsize = uieditfield(app.cubiodTab, 'text');
            app.cuboidsize.Value = '10;10;10';
            app.locationLabel = uilabel(app.cubiodTab);
            app.locationLabel.Text = 'location';
            app.cuboidposition = uieditfield(app.cubiodTab, 'text');
            app.cuboidposition.Value = '10;10;10';
            app.ButtonGroup = uibuttongroup(app.cubiodTab);
            app.maggradeButton = uiradiobutton(app.ButtonGroup);
            app.maggradeButton.Text = 'mag grade';
            app.maggradeButton.Value = true;
            app.magnButton = uiradiobutton(app.ButtonGroup);
            app.magnButton.Text = 'magn';
            app.maggrade = uieditfield(app.ButtonGroup, 'text');
            app.maggrade.Value = 'N40';
            app.mag = uieditfield(app.ButtonGroup, 'text');
            app.mag.Value = '1.38';
            app.add_cubiod = uibutton(app.cubiodTab, 'push');
            app.add_cubiod.ButtonPushedFcn = createCallbackFcn(app, @add_cubiodButtonPushed, true);
            app.add_cubiod.Text = 'add mag';
            app.clearcubiod = uibutton(app.cubiodTab, 'push');
            app.clearcubiod.ButtonPushedFcn = createCallbackFcn(app, @clearcubiodButtonPushed, true);
            app.clearcubiod.Text = 'clean up mag';
            app.StatorcolorLabel = uilabel(app.cubiodTab);
            app.StatorcolorLabel.Text = 'Stator color';
            app.color_fixd = uieditfield(app.cubiodTab, 'text');
            app.color_fixd.Value = '1;0;0';
            app.MovercolorLabel = uilabel(app.cubiodTab);
            app.MovercolorLabel.Text = 'Mover color';
            app.color_float = uieditfield(app.cubiodTab, 'text');
            app.color_float.Value = '0;1;0';
            app.unitsmNNmTLabel = uilabel(app.cubiodTab);
            app.unitsmNNmTLabel.Text = 'units：m，N，N/m,T';
            app.begin_cuboid = uibutton(app.cubiodTab, 'push');
            app.begin_cuboid.ButtonPushedFcn = createCallbackFcn(app, @begin_cuboidButtonPushed, true);
            app.begin_cuboid.Text = 'calculate';
            app.mag_dirLabel = uilabel(app.cubiodTab);
            app.mag_dirLabel.Text = 'mag_dir';
            app.cuboiddir = uieditfield(app.cubiodTab, 'text');
            app.cuboiddir.Value = '0;0;1';
            app.save_button = uibutton(app.cubiodTab, 'push');
            app.save_button.ButtonPushedFcn = createCallbackFcn(app, @save_buttonButtonPushed, true);
            app.save_button.Text = 'save data';
            app.ButtonGroup_2 = uibuttongroup(app.cubiodTab);
            app.xButton = uiradiobutton(app.ButtonGroup_2);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.ButtonGroup_2);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.ButtonGroup_2);
            app.zButton.Text = 'z';
            app.moverange = uieditfield(app.ButtonGroup_2, 'text');
            app.moverange.Value = '-50;50';
            app.dotnum = uieditfield(app.ButtonGroup_2, 'text');
            app.dotnum.Value = '500';
            app.mmLabel_3 = uilabel(app.ButtonGroup_2);
            app.mmLabel_3.Text = 'mm';
            app.movementLabel = uilabel(app.cubiodTab);
            app.movementLabel.Text = 'movement：';
            app.delbutton = uibutton(app.cubiodTab, 'push');
            app.delbutton.ButtonPushedFcn = createCallbackFcn(app, @delbuttonButtonPushed, true);
            app.delbutton.Text = 'delete mag';
            app.mmLabel = uilabel(app.cubiodTab);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.cubiodTab);
            app.mmLabel_2.Text = 'mm';
            app.load_button = uibutton(app.cubiodTab, 'push');
            app.load_button.ButtonPushedFcn = createCallbackFcn(app, @load_buttonButtonPushed, true);
            app.load_button.Text = 'read data';
            app.ButtonGroup_shuchu = uibuttongroup(app.cubiodTab);
            app.xButton_2 = uiradiobutton(app.ButtonGroup_shuchu);
            app.xButton_2.Text = 'x';
            app.xButton_2.Value = true;
            app.yButton_2 = uiradiobutton(app.ButtonGroup_shuchu);
            app.yButton_2.Text = 'y';
            app.zButton_2 = uiradiobutton(app.ButtonGroup_shuchu);
            app.zButton_2.Text = 'z';
            app.calculationofBonlinePanel = uipanel(app.cubiodTab);
            app.calculationofBonlinePanel.Title = 'calculation of B on line';
            app.delpoint = uibutton(app.calculationofBonlinePanel, 'push');
            app.delpoint.ButtonPushedFcn = createCallbackFcn(app, @delpointButtonPushed, true);
            app.delpoint.Text = 'delete line';
            app.addpoint = uibutton(app.calculationofBonlinePanel, 'push');
            app.addpoint.ButtonPushedFcn = createCallbackFcn(app, @addpointButtonPushed, true);
            app.addpoint.Text = 'add line';
            app.Bcalcu = uibutton(app.calculationofBonlinePanel, 'push');
            app.Bcalcu.ButtonPushedFcn = createCallbackFcn(app, @BcalcuButtonPushed, true);
            app.Bcalcu.Text = 'calculate';
            app.point1Label = uilabel(app.calculationofBonlinePanel);
            app.point1Label.Text = 'point1';
            app.beginpoint = uieditfield(app.calculationofBonlinePanel, 'text');
            app.beginpoint.Value = '20;0;0';
            app.point2Label = uilabel(app.calculationofBonlinePanel);
            app.point2Label.Text = 'point2';
            app.endpoint = uieditfield(app.calculationofBonlinePanel, 'text');
            app.endpoint.Value = '20;10;0';
            app.ButtonGroup_shuchu_2 = uibuttongroup(app.calculationofBonlinePanel);
            app.xButton_3 = uiradiobutton(app.ButtonGroup_shuchu_2);
            app.xButton_3.Text = 'x';
            app.xButton_3.Value = true;
            app.yButton_3 = uiradiobutton(app.ButtonGroup_shuchu_2);
            app.yButton_3.Text = 'y';
            app.zButton_3 = uiradiobutton(app.ButtonGroup_shuchu_2);
            app.zButton_3.Text = 'z';
            app.ZR_heiheiLabel = uilabel(app.cubiodTab);
            app.ZR_heiheiLabel.Text = 'ZR_heihei';
            app.HUSTMSELabel = uilabel(app.cubiodTab);
            app.HUSTMSELabel.Text = 'HUST MSE';
            app.onlytheBgeneratedbyLabel = uilabel(app.cubiodTab);
            app.onlytheBgeneratedbyLabel.Text = 'only the B generated by';
            app.thestatoriscalculatedLabel = uilabel(app.cubiodTab);
            app.thestatoriscalculatedLabel.Text = ' the stator is calculated ';
            app.UIAxes = uiaxes(app.cubiodTab);
            title(app.UIAxes, 'Calculation of interaction force between cubic magnets')
            xlabel(app.UIAxes, 'X(m)')
            ylabel(app.UIAxes, 'Y(m)')
            zlabel(app.UIAxes, 'Z(m)')
            app.UIAxes.XLim = [-0.1 0.1];
            app.UIAxes.YLim = [-0.1 0.1];
            app.UIAxes.ZLim = [-0.1 0.1];
            app.UIAxes.ALim = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = magcalculate_en

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end